classdef ScopeWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        MessageBox            matlab.ui.control.TextArea
        ConnectviaVISAButton  matlab.ui.control.StateButton
        VISADevBox            matlab.ui.control.ListBox
        ForceTriggerButton    matlab.ui.control.Button
        CH4RecordButton       matlab.ui.control.StateButton
        CH3RecordButton       matlab.ui.control.StateButton
        CH2RecordButton       matlab.ui.control.StateButton
        CH1RecordButton       matlab.ui.control.StateButton
        TriggerMode           matlab.ui.control.ListBox
        RefreshAllButton      matlab.ui.control.Button
        TriggerButton         matlab.ui.control.Button
        TriggerEdge           matlab.ui.control.ListBox
        TriggerChannel        matlab.ui.control.ListBox
        EditField_6           matlab.ui.control.NumericEditField
        EditField_6Label      matlab.ui.control.Label
        CHAN4ScaleVDivButton  matlab.ui.control.Button
        CHAN3ScaleVDivButton  matlab.ui.control.Button
        CHAN2ScaleVDivButton  matlab.ui.control.Button
        EditField_5           matlab.ui.control.NumericEditField
        EditField_5Label      matlab.ui.control.Label
        EditField_4           matlab.ui.control.NumericEditField
        EditField_4Label      matlab.ui.control.Label
        EditField_3           matlab.ui.control.NumericEditField
        EditField_3Label      matlab.ui.control.Label
        EditField_2           matlab.ui.control.NumericEditField
        EditField_2Label      matlab.ui.control.Label
        CHAN1ScaleVDivButton  matlab.ui.control.Button
        MainTimebasesButton   matlab.ui.control.Button
        EditField             matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        instDev % Description
        instWfm
        instConf
        previewTimer;
    end 
    
    methods (Access = private)
        function previewUpdate(app, obj, event)
            app.instConf.SetWaveformMode(app.instDev, 'NORM');
            [prewfm, pretime] = app.instWfm.GetWfm(app.instDev,1);
            app.MessageBox.Value = string(datetime);
            plot(app.UIAxes, pretime, prewfm);
            set(app.UIAxes, 'XLim', [min(pretime) max(pretime)]);
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('..\'));

            list = pyrunfile('VISAList.py','list');
            list = cell(list);
        
            if(isempty(list))
                error('No valid VISA device detected.');
            end

            for index = 1:length(list)
                app.VISADevBox.Items = [app.VISADevBox.Items  string(list(index))];                    
            end

            %TODO: Support more models.
            app.instConf = InstConfig_DS1000ZE();
            app.instWfm = InstWfm_DS1000ZE();
    
            app.previewTimer = timer("ExecutionMode","fixedRate","Period",1, ...
                "BusyMode","drop","TimerFcn",@app.previewUpdate);

        end

        % Value changed function: ConnectviaVISAButton
        function ConnectviaVISAButtonValueChanged(app, event)
            value = app.ConnectviaVISAButton.Value;
            if (value == true)
                app.instDev = visadev(app.VISADevBox.Value);
                app.MessageBox.Value = writeread(app.instDev,'*IDN?');
                start(app.previewTimer);
            else
                delete(app.instDev);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.MainTimebasesButton = uibutton(app.GridLayout, 'push');
            app.MainTimebasesButton.Text = 'Main Timebase (s)';
            app.CHAN1ScaleVDivButton = uibutton(app.GridLayout, 'push');
            app.CHAN1ScaleVDivButton.Text = 'CHAN 1 Scale (V/Div)';
            app.EditField_2Label = uilabel(app.GridLayout);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_3Label = uilabel(app.GridLayout);
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_4Label = uilabel(app.GridLayout);
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_5Label = uilabel(app.GridLayout);
            app.EditField_5Label.Text = '';
            app.EditField_5 = uieditfield(app.GridLayout, 'numeric');
            app.CHAN2ScaleVDivButton = uibutton(app.GridLayout, 'push');
            app.CHAN2ScaleVDivButton.Text = 'CHAN 2 Scale (V/Div)';
            app.CHAN3ScaleVDivButton = uibutton(app.GridLayout, 'push');
            app.CHAN3ScaleVDivButton.Text = 'CHAN 3 Scale (V/Div)';
            app.CHAN4ScaleVDivButton = uibutton(app.GridLayout, 'push');
            app.CHAN4ScaleVDivButton.Text = 'CHAN 4 Scale (V/Div)';
            app.EditField_6Label = uilabel(app.GridLayout);
            app.EditField_6Label.Text = '';
            app.EditField_6 = uieditfield(app.GridLayout, 'numeric');
            app.TriggerChannel = uilistbox(app.GridLayout);
            app.TriggerChannel.Items = {'CH 1', 'CH 2', 'CH 3', 'CH 4'};
            app.TriggerChannel.Value = 'CH 1';
            app.TriggerEdge = uilistbox(app.GridLayout);
            app.TriggerEdge.Items = {'POS EDGE', 'NEG EDGE', 'ANY EDGE'};
            app.TriggerEdge.Value = 'POS EDGE';
            app.TriggerButton = uibutton(app.GridLayout, 'push');
            app.TriggerButton.Text = 'Trigger';
            app.RefreshAllButton = uibutton(app.GridLayout, 'push');
            app.RefreshAllButton.Text = 'Refresh All';
            app.TriggerMode = uilistbox(app.GridLayout);
            app.TriggerMode.Items = {'AUTO', 'NORM', 'SINGLE'};
            app.TriggerMode.Value = 'AUTO';
            app.CH1RecordButton = uibutton(app.GridLayout, 'state');
            app.CH1RecordButton.Text = 'CH1 Record';
            app.CH2RecordButton = uibutton(app.GridLayout, 'state');
            app.CH2RecordButton.Text = 'CH2 Record';
            app.CH3RecordButton = uibutton(app.GridLayout, 'state');
            app.CH3RecordButton.Text = 'CH3 Record';
            app.CH4RecordButton = uibutton(app.GridLayout, 'state');
            app.CH4RecordButton.Text = 'CH4 Record';
            app.ForceTriggerButton = uibutton(app.GridLayout, 'push');
            app.ForceTriggerButton.Text = 'Force Trigger';
            app.VISADevBox = uilistbox(app.GridLayout);
            app.VISADevBox.Items = {};
            app.VISADevBox.Value = {};
            app.ConnectviaVISAButton = uibutton(app.GridLayout, 'state');
            app.ConnectviaVISAButton.ValueChangedFcn = createCallbackFcn(app, @ConnectviaVISAButtonValueChanged, true);
            app.ConnectviaVISAButton.Text = 'Connect via VISA';
            app.MessageBox = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScopeWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end